classdef MMRT_GUI_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AddfolderButton                matlab.ui.control.Button
        ThresholdflightdurationEditField  matlab.ui.control.NumericEditField
        ThresholdflightdurationEditFieldLabel  matlab.ui.control.Label
        ThresholdrvalueEditField       matlab.ui.control.NumericEditField
        ThresholdrvalueLabel           matlab.ui.control.Label
        MooresmodifiedRayleightestLabel  matlab.ui.control.Label
        egMW_Label                     matlab.ui.control.Label
        addsimpleRayleightestCheckBox  matlab.ui.control.CheckBox
        FileidentifierEditField        matlab.ui.control.EditField
        FileidentifierEditFieldLabel   matlab.ui.control.Label
        FolderpathEditField            matlab.ui.control.EditField
        FolderpathEditFieldLabel       matlab.ui.control.Label
        AnalysisfigureButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        POP% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AnalysisfigureButton
        function AnalysisfigureButtonPushed(app, event)
           path_name = app.FolderpathEditField.Value;
           file_identifier= app.FileidentifierEditField.Value;
           POP= app.addsimpleRayleightestCheckBox.Value;
           f= app.ThresholdrvalueEditField.Value;
           Duration_flight= app.ThresholdflightdurationEditField.Value;
           Series= app.FileidentifierEditField.Value;
           assignin('base','Series', Series)
           assignin('base','Duration_flight', Duration_flight)
            assignin('base','f',f)
            assignin('base','POP',POP)
            assignin('base','path_name',path_name);
            assignin('base','file_identifier',file_identifier);
           run('MMRT')
        end

        % Value changed function: FolderpathEditField
        function FolderpathEditFieldValueChanged(app, event)
            value = app.FolderpathEditField.Value;
            
        end

        % Value changed function: FileidentifierEditField
        function FileidentifierEditFieldValueChanged(app, event)
            value = app.FileidentifierEditField.Value;
            
        end

        % Value changed function: addsimpleRayleightestCheckBox
        function addsimpleRayleightestCheckBoxValueChanged(app, event)
            value = app.addsimpleRayleightestCheckBox.Value;
            
        end

        % Value changed function: ThresholdrvalueEditField
        function ThresholdrvalueEditFieldValueChanged(app, event)
            value = app.ThresholdrvalueEditField.Value;
            
        end

        % Value changed function: ThresholdflightdurationEditField
        function ThresholdflightdurationEditFieldValueChanged(app, event)
            value = app.ThresholdflightdurationEditField.Value;
            
        end

        % Button pushed function: AddfolderButton
        function AddfolderButtonPushed(app, event)
           path_name = uigetdir(path)
           app.FolderpathEditField.Value=path_name
           assignin('base','path_name',path_name);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AnalysisfigureButton = uibutton(app.UIFigure, 'push');
            app.AnalysisfigureButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisfigureButtonPushed, true);
            app.AnalysisfigureButton.Text = 'Analysis & figure';
            app.FolderpathEditFieldLabel = uilabel(app.UIFigure);
            app.FolderpathEditFieldLabel.Text = 'Folder (path)';
            app.FolderpathEditField = uieditfield(app.UIFigure, 'text');
            app.FolderpathEditField.ValueChangedFcn = createCallbackFcn(app, @FolderpathEditFieldValueChanged, true);
            app.FileidentifierEditFieldLabel = uilabel(app.UIFigure);
            app.FileidentifierEditFieldLabel.Text = 'File identifier';
            app.FileidentifierEditField = uieditfield(app.UIFigure, 'text');
            app.FileidentifierEditField.ValueChangedFcn = createCallbackFcn(app, @FileidentifierEditFieldValueChanged, true);
            app.addsimpleRayleightestCheckBox = uicheckbox(app.UIFigure);
            app.addsimpleRayleightestCheckBox.ValueChangedFcn = createCallbackFcn(app, @addsimpleRayleightestCheckBoxValueChanged, true);
            app.addsimpleRayleightestCheckBox.Text = ' add simple Rayleigh test';
            app.addsimpleRayleightestCheckBox.Value = true;
            app.egMW_Label = uilabel(app.UIFigure);
            app.egMW_Label.Text = 'e.g. MW_';
            app.MooresmodifiedRayleightestLabel = uilabel(app.UIFigure);
            app.MooresmodifiedRayleightestLabel.Text = 'Moore''s modified Rayleigh test';
            app.ThresholdrvalueLabel = uilabel(app.UIFigure);
            app.ThresholdrvalueLabel.Text = 'Threshold "r-value"';
            app.ThresholdrvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdrvalueEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdrvalueEditFieldValueChanged, true);
            app.ThresholdflightdurationEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdflightdurationEditFieldLabel.Text = 'Threshold "flight duration"';
            app.ThresholdflightdurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdflightdurationEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdflightdurationEditFieldValueChanged, true);
            app.ThresholdflightdurationEditField.Value = 1500;
            app.AddfolderButton = uibutton(app.UIFigure, 'push');
            app.AddfolderButton.ButtonPushedFcn = createCallbackFcn(app, @AddfolderButtonPushed, true);
            app.AddfolderButton.Text = 'Add folder';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MMRT_GUI_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end